classdef DataAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PrestimsEditFieldLabel        matlab.ui.control.Label
        PrestimsEditField             matlab.ui.control.NumericEditField
        StimsEditFieldLabel           matlab.ui.control.Label
        StimsEditField                matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        PoststimsEditFieldLabel       matlab.ui.control.Label
        PoststimsEditField            matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        TrialdurationsEditFieldLabel  matlab.ui.control.Label
        TrialdurationsEditField       matlab.ui.control.NumericEditField
        StimVEditFieldLabel           matlab.ui.control.Label
        StimVEditField                matlab.ui.control.NumericEditField
        BesselFilterLOEditFieldLabel  matlab.ui.control.Label
        BesselFilterLOEditField       matlab.ui.control.NumericEditField
        StimFreqhzEditFieldLabel      matlab.ui.control.Label
        StimFreqhzEditField           matlab.ui.control.NumericEditField
        BindursEditFieldLabel         matlab.ui.control.Label
        BindursEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        
        prestim_duration_s
        poststim_duration_s
        stim_duration_s
        stim_hz
        stim_voltage
        fs
        stim_signal
        nsample
        total_stim_duration
    end
    
    methods (Access = public)
        
        function update_trial_duration(app)
            app.TrialdurationsEditField.Value = app.prestim_duration_s+ ...
            app.poststim_duration_s + app.stim_duration_s;
            app.total_stim_duration = app.TrialdurationsEditField.Value;
%             dt = 0.001;
%             baseline = -0.1;
%             pre_stim_signal = zeros(1:floor(app.prestim_duration_s/dt));
%             post_stim_signal = zeros(1:floor(app.poststim_duration_s/dt));
%             t = dt:dt:app.stim_duration_s;
%             app.stim_signal = sin(2*pi*app.stim_hz*t);
%             app.stim_signal = sign(app.stim_signal)*app.stim_voltage;
%             app.fs = 1/dt;
%             app.stim_signal = [pre_stim_signal+baseline app.stim_signal post_stim_signal+baseline baseline];
%             app.nsample = app.total_stim_duration * app.fs;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.prestim_duration_s = app.PrestimsEditField.Value;
            app.stim_duration_s = app.StimsEditField.Value;
            app.poststim_duration_s = app.PoststimsEditField.Value;
        end

        % Value changed function: PrestimsEditField
        function PrestimsEditFieldValueChanged(app, event)
            app.prestim_duration_s = app.PrestimsEditField.Value;
            app.update_trial_duration();
        end

        % Value changed function: StimsEditField
        function StimsEditFieldValueChanged(app, event)
            app.stim_duration_s = app.StimsEditField.Value;
            app.update_trial_duration();
        end

        % Value changed function: PoststimsEditField
        function PoststimsEditFieldValueChanged(app, event)
            app.poststim_duration_s = app.PoststimsEditField.Value;
            app.update_trial_duration();
        end

        % Value changed function: StimVEditField
        function StimVEditFieldValueChanged(app, event)
            app.stim_voltage = app.StimVEditField.Value;
        end

        % Value changed function: StimFreqhzEditField
        function StimFreqhzEditFieldValueChanged(app, event)
            app.stim_hz = app.StimFreqhzEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrestimsEditFieldLabel = uilabel(app.UIFigure);
            app.PrestimsEditFieldLabel.Text = 'Pre-stim (s)';
            app.PrestimsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrestimsEditField.ValueChangedFcn = createCallbackFcn(app, @PrestimsEditFieldValueChanged, true);
            app.PrestimsEditField.Value = 4;
            app.StimsEditFieldLabel = uilabel(app.UIFigure);
            app.StimsEditFieldLabel.Text = 'Stim (s)';
            app.StimsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimsEditField.ValueChangedFcn = createCallbackFcn(app, @StimsEditFieldValueChanged, true);
            app.StimsEditField.Value = 4;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '+';
            app.PoststimsEditFieldLabel = uilabel(app.UIFigure);
            app.PoststimsEditFieldLabel.Text = 'Post-stim (s)';
            app.PoststimsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoststimsEditField.ValueChangedFcn = createCallbackFcn(app, @PoststimsEditFieldValueChanged, true);
            app.PoststimsEditField.Value = 10;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.TrialdurationsEditFieldLabel = uilabel(app.UIFigure);
            app.TrialdurationsEditFieldLabel.Text = 'Trial duration (s)';
            app.TrialdurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialdurationsEditField.Value = 18;
            app.StimVEditFieldLabel = uilabel(app.UIFigure);
            app.StimVEditFieldLabel.Text = 'Stim (V)';
            app.StimVEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimVEditField.ValueChangedFcn = createCallbackFcn(app, @StimVEditFieldValueChanged, true);
            app.StimVEditField.Value = 1;
            app.BesselFilterLOEditFieldLabel = uilabel(app.UIFigure);
            app.BesselFilterLOEditFieldLabel.Text = 'BesselFilter LO';
            app.BesselFilterLOEditField = uieditfield(app.UIFigure, 'numeric');
            app.BesselFilterLOEditField.Value = 100;
            app.StimFreqhzEditFieldLabel = uilabel(app.UIFigure);
            app.StimFreqhzEditFieldLabel.Text = 'Stim Freq (hz)';
            app.StimFreqhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimFreqhzEditField.ValueChangedFcn = createCallbackFcn(app, @StimFreqhzEditFieldValueChanged, true);
            app.StimFreqhzEditField.Value = 10;
            app.BindursEditFieldLabel = uilabel(app.UIFigure);
            app.BindursEditFieldLabel.Text = 'Bin dur (s)';
            app.BindursEditField = uieditfield(app.UIFigure, 'numeric');
            app.BindursEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end